classdef FlightCockpit_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FlightInstrumentsFlightDataPlaybackUIFigure  matlab.ui.Figure
        StopButton                     matlab.ui.control.Button
        StartVisualizationButton       matlab.ui.control.Button
        FlightCockpitDataDisplayLabel  matlab.ui.control.Label
        ClimbIndicator                 Aero.ui.control.ClimbIndicator
        HeadingIndicator               Aero.ui.control.HeadingIndicator
        TurnCoordinator                Aero.ui.control.TurnCoordinator
        Altimeter                      Aero.ui.control.Altimeter
        ArtificialHorizon              Aero.ui.control.ArtificialHorizon
        AirspeedIndicator              Aero.ui.control.AirspeedIndicator
        UIAxes_trajectory              matlab.ui.control.UIAxes
        UIAxes_alt_eas                 matlab.ui.control.UIAxes
        UIAxes_rates                   matlab.ui.control.UIAxes
        UIAxes_euler_angles            matlab.ui.control.UIAxes
    end

   
    properties (Access = public) 
        timer   % variable holds timer for visualization
        tDelta  % delay between timer steps
        currentIndex % current time index in interpolated data
        currentTime % current time of visualization timer
        interpData % Holds interpolated data and timesteps
        
        eulerPlot % plot for Euler angles in UI
        ratesPlot % plot for rates (p,q,r) in UI
        altEasPlot % plot for altitude and EAS
        trajPlot % 3D plot of trajectory

        patchTop
        patchBot


        simdata % Saved flight data [time X Y Z phi theta psi]
        animObj % Aero.Animation object
        output  % The output states of the GTM Model
        t       % Time data
        tplot   % Interpolated time data for plotting
        Xeom    % EOM state data
        y       % State data from simulation
        dist_lat % Latitude distance
        dist_lon % Longitude distance
        alt      % Altitude data
        Vehicletop      % Top view of the vehicle
        Vehiclebot      % Bottom view of the vehicle
        simulationTime
    end
    
    % my own methods
    methods (Access = private)
        
        function initPlots(app)
            disp('initializing plots')
        
            % Preallocate Euler Angles Plot with NaN to allow dynamic updates
            app.eulerPlot = plot(app.UIAxes_euler_angles, app.interpData.t, NaN(length(app.interpData.t), 3), LineWidth=1);
            xlabel(app.UIAxes_euler_angles, 't [s]');
            ylabel(app.UIAxes_euler_angles, 'Euler Angles [deg]');
            legend(app.UIAxes_euler_angles, '\phi', '\theta', '\psi')
            
            % Preallocate Rates Plot with NaN to allow dynamic updates
            app.ratesPlot = plot(app.UIAxes_rates, app.interpData.t, NaN(length(app.interpData.t), 3), LineWidth=1);
            xlabel(app.UIAxes_rates, 't [s]');
            ylabel(app.UIAxes_rates, 'Rates [deg/s]');
            legend(app.UIAxes_rates,'p','q','r')

            % Preallocate altitude and EAS Plot with NaN to allow dynamic updates
            app.altEasPlot = plot(app.UIAxes_alt_eas, app.interpData.t, NaN(length(app.interpData.t), 2), LineWidth=1);
            xlabel(app.UIAxes_alt_eas, 't [s]');
            ylabel(app.UIAxes_alt_eas, '\gamma (deg), Equivalent Airspeed (knots)');
            legend(app.UIAxes_alt_eas,{'\gamma', 'EAS'}, 'Location', 'SouthEast');
 

            % 3D plot of trajectory
            app.trajPlot = plot3(app.UIAxes_trajectory, ...
            app.interpData.pos(:,1), app.interpData.pos(:,2), app.interpData.pos(:,3));
            xlabel(app.UIAxes_trajectory,'x')
            ylabel(app.UIAxes_trajectory,'y')
            zlabel(app.UIAxes_trajectory,'altitdue')
            axis(app.UIAxes_trajectory, 'equal')
            disp('plots were initialized')
        end


        function DCM = euler321(app, p)
            % euler321: Computes the Direction Cosine Matrix for a 321 (yaw-pitch-roll) rotation sequence
            % 
            % Inputs:
            %   p    - 1x3 vector of Euler angles [phi, theta, psi] in radians
            % 
            % Outputs:
            %   DCM  - 3x3 Direction Cosine Matrix
        
            % Extract Euler angles
            phi = p(1);    % Roll
            theta = p(2);  % Pitch
            psi = p(3);    % Yaw
        
            % Compute cosines and sines of the angles
            c1 = cos(phi);
            s1 = sin(phi);
            c2 = cos(theta);
            s2 = sin(theta);
            c3 = cos(psi);
            s3 = sin(psi);
        
            % Construct the Direction Cosine Matrix
            DCM = [ c2*c3,          c2*s3,         -s2;
                    s1*s2*c3 - c1*s3,  s1*s2*s3 + c1*c3, s1*c2;
                    c1*s2*c3 + s1*s3,  c1*s2*s3 - s1*c3, c1*c2];
        end


        % Timer update function
        function updateTimer(app, totalTime)
            % Update the current time
            app.currentTime = app.currentTime + app.timer.Period;  % Increment the current time
            app.currentIndex = app.currentIndex + 1;

            k = app.currentIndex;
            
            % Update Euler Angles plot
            set(app.eulerPlot(1), 'XData', app.interpData.t(1:app.currentIndex), ...
                                  'YData', app.interpData.eulerAngles(1:k, 1)*180/pi);
            set(app.eulerPlot(2), 'XData', app.interpData.t(1:k), ...
                                  'YData', app.interpData.eulerAngles(1:k, 2)*180/pi);
            set(app.eulerPlot(3), 'XData', app.interpData.t(1:k), ...
                                  'YData', app.interpData.eulerAngles(1:k, 3)*180/pi);
            
            % Update Rates plot
            set(app.ratesPlot(1), 'XData', app.interpData.t(1:k), ...
                                  'YData', app.interpData.rates(1:k, 1)*180/pi);
            set(app.ratesPlot(2), 'XData', app.interpData.t(1:k), ...
                                  'YData', app.interpData.rates(1:k, 2)*180/pi);
            set(app.ratesPlot(3), 'XData', app.interpData.t(1:k), ...
                                  'YData', app.interpData.rates(1:k, 3)*180/pi);


            % Update flight path and EAS plot
            set(app.altEasPlot(1), 'XData', app.interpData.t(1:k), ...
                                  'YData', app.interpData.y(1:k, 1)); % EAS
            set(app.altEasPlot(2), 'XData', app.interpData.t(1:k), ...
                                  'YData', app.interpData.y(1:k, 5)); % gamma


            % Draw plane in 3D-plot
            position = app.interpData.pos(k,:)';
            offset=repmat(position,1,size(app.Vehicletop,2));
            euler = app.interpData.eulerAngles(k, :);
            PtmpTop=diag([1,1,-1])*transpose(app.euler321(euler))*app.Vehicletop + offset;
            PtmpBot=diag([1,1,-1])*transpose(app.euler321(euler))*app.Vehiclebot + offset;

            % plot top and bottom
            delete(app.patchTop);
            delete(app.patchBot)
            app.patchTop = patch(app.UIAxes_trajectory,PtmpTop(1,:),PtmpTop(2,:),PtmpTop(3,:),'g');
            app.patchBot = patch(app.UIAxes_trajectory,PtmpBot(1,:),PtmpBot(2,:),PtmpBot(3,:),'c');


            % update UI components
            % Calculate vertical speed
            kts2mps = 0.514444;  % Knots to m/s conversion
            vel_z = (app.interpData.y(k,1) * kts2mps) * sind(app.interpData.y(k,3)) * cosd(app.interpData.y(k,4));
            app.ClimbIndicator.ClimbRate = convvel(-vel_z, 'm/s', 'ft/min');
            
            % airspeed indicator
            app.AirspeedIndicator.Airspeed = app.interpData.y(k,1);

            % Turn Coordinator
            roll_rate = app.interpData.y(k,10);
            yaw_rate = app.interpData.y(k,12);
            bank_angle = app.interpData.y(k,16);
            app.TurnCoordinator.Turn = convangvel(roll_rate * sin(bank_angle) + yaw_rate * cos(bank_angle), 'rad/s', 'deg/s');
            app.TurnCoordinator.Slip = 1/(2*pi) * convang(atan(yaw_rate * app.interpData.y(k,1) / 9.81) - app.interpData.y(k,16), 'rad', 'deg');


            % Artificial Horizon
            app.ArtificialHorizon.Roll = convang(app.interpData.y(k,16), 'rad', 'deg');
            app.ArtificialHorizon.Pitch = convang(app.interpData.y(k,17), 'rad', 'deg');
            
            % Heading Indicator
            app.HeadingIndicator.Heading = convang(app.interpData.y(k,18), 'rad', 'deg');

            % Altimeter Altitude
            app.Altimeter.Altitude = convlength(app.interpData.pos(k,3), 'm', 'ft');


            % Stop the timer if the total time is exceeded
            if app.currentTime >= totalTime
                stop(app.timer);
                disp('Simulation complete.');
            end
        end


        % function updatePlots(app)
        %     % update Euler data in plot
        % 
        % end

        function updateInstruments(app)
            idx = app.currentIndex;
            disp("Updating instruments")
        end

        function preprocessData(app, data);
            
            % Init interpolated data
            app.interpData = struct();
            app.simulationTime = data.t(end);
            app.interpData.t = linspace(0, app.simulationTime, round(app.simulationTime/app.tDelta))
            disp('--- Preprocessing data ---')
            % Interpolate each column of Xeom over the time points t to match tplot
            for i = 1:size(data.Xeom, 2)
                xeomInterp(:, i) = interp1(data.t, data.Xeom(:, i), app.interpData.t, 'linear');
            end

            for i = 1:size(data.y, 2)
                yInterp(:, i) = interp1(data.t, data.y(:, i), app.interpData.t, 'linear');
            end

            % save interpolated data
            app.interpData.XEOM = xeomInterp;
            app.interpData.y = yInterp;

            % Positions in m
            distFromLat=(app.interpData.XEOM(:,7)-app.interpData.XEOM(1,7)) * 180/pi*364100.79; % lat to dist
            distFromLong=(app.interpData.XEOM(:,8)-app.interpData.XEOM(1,8)) * 180/pi*291925.24; % long to dist
            alti=app.interpData.XEOM(:,9);
            feetToMeters = 0.3048;
            app.interpData.pos = [distFromLat,distFromLong,alti].*feetToMeters;
            app.interpData.pos(:,3) = app.interpData.pos(:,3) - min(app.interpData.pos(:,3));
            app.interpData.eulerAngles = app.interpData.XEOM(:,10:12);
            app.interpData.rates = app.interpData.XEOM(:,4:6);
        end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% About
            %
            % Author:     Omar Mourad
            % Email:      <mailto: st181901@stud.uni-stuttgart.de>
            % Created:    28.03.2024 
            
            %% ------------------ Initializing the Upset Condition ------------------
            selectedSimulationType = 'Oscillatory Spin';  % Change this to 'Steep Spiral' as needed
            runSim = false; % Run simulation before visualization?
            app.tDelta = 0.1; % set timer offset for plotting

            %% Initialize the simulation
            switch selectedSimulationType
                case 'Oscillatory Spin'
                    if runSim
                        run('OscillatorySpin.m'); % Run the Oscillatory Spin simulation
                    end
                    data = load('OscillatorySpinData.mat'); % Load corresponding data
                case 'Steep Spiral'
                    if runSim
                        run('SteepSpiral.m'); % Run the Steep Spiral simulation
                    end
                    data = load('SteepSpiralData.mat'); % Load corresponding data
                otherwise
                    error('Unknown simulation type selected.');
            end

            % Assign data to app properties for further use
            app.preprocessData(data);

            %% Define simple airplane shape
            scale = 10;
            x1 = scale * ([0.0, -0.5, -2.0, -3.0, -4.0, -3.25, -5.5, -6.0, -6.0] + 3.0);
            y1 = scale * [0.0, 0.5, 0.5, 4.25, 4.5, 0.5, 0.5, 1.5, 0.0];
            app.Vehicletop = [[x1, fliplr(x1)]; [y1, fliplr(-y1)]; -0.01 * ones(1, 2 * length(x1))];
            app.Vehiclebot = [[x1, fliplr(x1)]; [y1, fliplr(-y1)]; 0.01 * ones(1, 2 * length(x1))];
        end

        % Callback function
        function Time000secSliderValueChanging(app, event)

            
        end

        % Close request function: 
        % FlightInstrumentsFlightDataPlaybackUIFigure
        function FlightInstrumentsFlightDataPlaybackUIFigureCloseRequest(app, event)
            % Close animation figure with app
            delete(app.animObj);
            delete(app); 
           
        end

        % Button pushed function: StartVisualizationButton
        function StartVisualizationButtonPushed(app, event)
            app.initPlots()

            % Define the total simulation time
                totalTime = app.simulationTime;  % Assuming app.simulationTime holds the total time in seconds
            
                % Initialize the current time
                app.currentTime = 0;  % Initialize to 0 at the start of the simulation
                app.currentIndex = 1;
            
                % Create a timer
                if isempty(app.timer) || ~strcmp(app.timer.Running, 'on')
                    app.timer = timer;
                    app.timer.StartDelay = 4; % Start immediately
                    app.timer.Period = app.tDelta; % Update every 0.1 seconds (adjust as needed)
                    app.timer.ExecutionMode = 'fixedRate'; % Fixed rate execution
                    app.timer.BusyMode = 'queue'; % Queue additional executions if the timer is busy
                    app.timer.TimerFcn = @(~, ~) updateTimer(app, totalTime); % Update function
            
                    % Start the timer
                    start(app.timer);
                end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if ~isempty(app.timer)
                stop(app.timer);
                disp('timer stopped')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FlightInstrumentsFlightDataPlaybackUIFigure = uifigure('Visible', 'off');
            app.FlightInstrumentsFlightDataPlaybackUIFigure.Name = 'Flight Instruments - Flight Data Playback';
            app.FlightInstrumentsFlightDataPlaybackUIFigure.CloseRequestFcn = createCallbackFcn(app, @FlightInstrumentsFlightDataPlaybackUIFigureCloseRequest, true);
            app.UIAxes_euler_angles = uiaxes(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            title(app.UIAxes_euler_angles, 'Euler Angles')
            xlabel(app.UIAxes_euler_angles, 't [s]')
            ylabel(app.UIAxes_euler_angles, 'Angles [deg]')
            zlabel(app.UIAxes_euler_angles, 'Z')
            app.UIAxes_rates = uiaxes(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            title(app.UIAxes_rates, 'Angular Rates')
            xlabel(app.UIAxes_rates, 't [s]')
            ylabel(app.UIAxes_rates, 'Rates [deg]')
            zlabel(app.UIAxes_rates, 'Z')
            app.UIAxes_alt_eas = uiaxes(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            title(app.UIAxes_alt_eas, 'Flight Path Angle and Equivalent Air Speed')
            xlabel(app.UIAxes_alt_eas, 't [s]')
            ylabel(app.UIAxes_alt_eas, '\gamma / EAS')
            zlabel(app.UIAxes_alt_eas, 'Z')
            app.UIAxes_trajectory = uiaxes(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            title(app.UIAxes_trajectory, '3D Trajectory Plot')
            xlabel(app.UIAxes_trajectory, 't [s]')
            ylabel(app.UIAxes_trajectory, 'velocity [m/s]')
            zlabel(app.UIAxes_trajectory, 'Z')
            app.AirspeedIndicator = uiaeroairspeed(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.AirspeedIndicator.Limits = [25 250];
            app.ArtificialHorizon = uiaerohorizon(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.Altimeter = uiaeroaltimeter(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.TurnCoordinator = uiaeroturn(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.HeadingIndicator = uiaeroheading(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.ClimbIndicator = uiaeroclimb(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.ClimbIndicator.MaximumRate = 8000;
            app.FlightCockpitDataDisplayLabel = uilabel(app.FlightInstrumentsFlightDataPlaybackUIFigure);
            app.FlightCockpitDataDisplayLabel.Text = 'Flight Cockpit Data Display';
            app.StartVisualizationButton = uibutton(app.FlightInstrumentsFlightDataPlaybackUIFigure, 'push');
            app.StartVisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @StartVisualizationButtonPushed, true);
            app.StartVisualizationButton.Text = 'StartVisualization';
            app.StopButton = uibutton(app.FlightInstrumentsFlightDataPlaybackUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.FlightInstrumentsFlightDataPlaybackUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FlightCockpit_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FlightInstrumentsFlightDataPlaybackUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FlightInstrumentsFlightDataPlaybackUIFigure)
        end
    end
end